classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PredictButton                 matlab.ui.control.Button
        ScrewconfigurationDropDown    matlab.ui.control.DropDown
        ScrewconfigurationDropDownLabel  matlab.ui.control.Label
        ScrewspeedrpmEditField        matlab.ui.control.EditField
        ScrewspeedrpmEditFieldLabel   matlab.ui.control.Label
        LiquidbinderwwEditField       matlab.ui.control.EditField
        LiquidbinderwwEditFieldLabel  matlab.ui.control.Label
        LiquidContentEditField        matlab.ui.control.EditField
        LiquidContentEditFieldLabel   matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

% Private properties for models and internal data
    properties (Access = private)
        mdl_coverage   % Property to store the model for seed coverage
        mdl_number     % Property to store the model for seeded granules
        data_means     % Property to store the means of input data
        data_stds      % Property to store the standard deviations of input data
        config_categories % Property to store screw configuration categories
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Load the data
        data = readtable('Model_data.csv');  % Ensure the CSV is available in the working directory

        % Convert categorical variable
        data.Screw_Configuration = categorical(data.Screw_Configuration);

        % Normalize continuous variables
        data.Screw_speed_norm = (data.Screw_speed - mean(data.Screw_speed)) / std(data.Screw_speed);
        data.Liquid_content_norm = (data.Liquid_content - mean(data.Liquid_content)) / std(data.Liquid_content);
        data.Liquid_binder_norm = (data.Liquid_binder - mean(data.Liquid_binder)) / std(data.Liquid_binder);

        % Prepare the data for modeling
        X = [data.Screw_speed_norm, data.Liquid_content_norm, data.Liquid_binder_norm];
        y_coverage = data.Seed_coverage;
        y_number = data.number_seeded;

        % Create dummy variables for Screw_Configuration
        config_dummy = dummyvar(data.Screw_Configuration);
        X = [X, config_dummy(:, 1:end-1)]; % Exclude one category to avoid multicollinearity

        % Train models using stepwise regression
        app.mdl_coverage = stepwiselm(X, y_coverage, 'Upper', 'quadratic', 'Criterion', 'aic');
        app.mdl_number = stepwiselm(X, y_number, 'Upper', 'quadratic', 'Criterion', 'aic');

        % Store normalization data in app properties
        app.data_means = [mean(data.Screw_speed), mean(data.Liquid_content), mean(data.Liquid_binder)];
        app.data_stds = [std(data.Screw_speed), std(data.Liquid_content), std(data.Liquid_binder)];
        app.config_categories = {'LS', 'MS', 'HSS', 'HSL'};  % Example categories
          
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
 % Retrieve input values
    liquidContent = str2double(app.LiquidContentEditField.Value);
    liquidBinder = str2double(app.LiquidbinderwwEditField.Value);
    screwSpeed = str2double(app.ScrewspeedrpmEditField.Value);
    screwConfig = app.ScrewconfigurationDropDown.Value;
    
    % Validate inputs
    if isnan(liquidContent) || isnan(liquidBinder) || isnan(screwSpeed)
        uialert(app.UIFigure, 'Please enter valid numerical values.', 'Invalid Input');
        return;
    end

    % Prepare the data for prediction
    new_data = table(screwSpeed, liquidContent, liquidBinder, ...
        categorical({screwConfig}), 'VariableNames', ...
        {'Screw_speed', 'Liquid_content', 'Liquid_binder', 'Screw_Configuration'});
    
    % Standardize the inputs based on the training data means and stds
    new_data.Screw_speed = (new_data.Screw_speed - app.data_means(1)) / app.data_stds(1);
    new_data.Liquid_content = (new_data.Liquid_content - app.data_means(2)) / app.data_stds(2);
    new_data.Liquid_binder = (new_data.Liquid_binder - app.data_means(3)) / app.data_stds(3);

    % Create dummy variables for Screw Configuration
    config_dummy = dummyvar(categorical(new_data.Screw_Configuration, app.config_categories));
    new_X = [new_data.Screw_speed, new_data.Liquid_content, new_data.Liquid_binder, config_dummy(:, 1:end-1)];

    % Predict values for coverage and number
    predicted_coverage = predict(app.mdl_coverage, new_X);
    predicted_number = predict(app.mdl_number, new_X);

    % Define probability criteria
    % Expected number of seeded granules is approximated based on screw speed
    expected_seeds = interp1([100, 300, 500], [18, 20, 13], screwSpeed, 'linear', 'extrap');  % Example logic
    
    % Check if predicted number of granules is within 85%-115% of expected
    prob_number = (predicted_number >= 0.85 * expected_seeds) && (predicted_number <= 1.15 * expected_seeds);
    
    % Check if predicted seed coverage is above a certain threshold (e.g., 40%)
    prob_coverage = predicted_coverage >= 40;

    % Calculate overall probability as the mean of both criteria (or customize this)
    probability = mean([prob_number, prob_coverage]);

    % Display the predictions in UITable
    app.UITable.Data = {predicted_coverage, predicted_number, probability};
    
    % Plot predicted number of seeded granules on UIAxes
    scatter3(app.UIAxes, liquidContent, liquidBinder, predicted_number, 'filled');
    title(app.UIAxes, 'Predicted Number of Seeded Granules');
    xlabel(app.UIAxes, 'Liquid Content');
    ylabel(app.UIAxes, 'Liquid Binder');
    zlabel(app.UIAxes, 'Number of Seeded Granules');
    
    % Plot predicted seed coverage on UIAxes2
    scatter3(app.UIAxes2, liquidContent, liquidBinder, predicted_coverage, 'filled');
    title(app.UIAxes2, 'Predicted Seed Coverage');
    xlabel(app.UIAxes2, 'Liquid Content');
    ylabel(app.UIAxes2, 'Liquid Binder');
    zlabel(app.UIAxes2, 'Seed Coverage');             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Predicted Number of Seeded Granules')
            xlabel(app.UIAxes, 'Liquid Content')
            ylabel(app.UIAxes, 'Liquid Binder')
            zlabel(app.UIAxes, 'Number of Seeded Granules')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Predicted Seed Coverage')
            xlabel(app.UIAxes2, 'Liquid Content')
            ylabel(app.UIAxes2, 'Liquid Binder')
            zlabel(app.UIAxes2, 'Number of Seeded Granules')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Predicted Seed Coverage'; 'Predicted Number of Seeded Granules'; 'Probability of Producing Seeded Granules'};
            app.UITable.RowName = {};
            app.LiquidContentEditFieldLabel = uilabel(app.UIFigure);
            app.LiquidContentEditFieldLabel.Text = 'Liquid Content';
            app.LiquidContentEditField = uieditfield(app.UIFigure, 'text');
            app.LiquidbinderwwEditFieldLabel = uilabel(app.UIFigure);
            app.LiquidbinderwwEditFieldLabel.Text = 'Liquid binder (w/w%)';
            app.LiquidbinderwwEditField = uieditfield(app.UIFigure, 'text');
            app.ScrewspeedrpmEditFieldLabel = uilabel(app.UIFigure);
            app.ScrewspeedrpmEditFieldLabel.Text = 'Screw speed (rpm)';
            app.ScrewspeedrpmEditField = uieditfield(app.UIFigure, 'text');
            app.ScrewconfigurationDropDownLabel = uilabel(app.UIFigure);
            app.ScrewconfigurationDropDownLabel.Text = 'Screw configuration';
            app.ScrewconfigurationDropDown = uidropdown(app.UIFigure);
            app.ScrewconfigurationDropDown.Items = {'LS', 'MS', 'HSS', 'HSL'};
            app.ScrewconfigurationDropDown.Value = 'LS';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end